classdef PluckedStringGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AmbientPluckUIFigure       matlab.ui.Figure
        AnimateCheckBox            matlab.ui.control.CheckBox
        TimeDomainCheckBox         matlab.ui.control.CheckBox
        FrequnecyDomainCheckBox    matlab.ui.control.CheckBox
        SpectrogramCheckBox        matlab.ui.control.CheckBox
        PlotsLabel                 matlab.ui.control.Label
        SavewavfileCheckBox        matlab.ui.control.CheckBox
        PluckButton                matlab.ui.control.Button
        FundementalFrequencyHzEditFieldLabel  matlab.ui.control.Label
        FundementalFrequencyHzEditField  matlab.ui.control.NumericEditField
        PluckPositionSliderLabel   matlab.ui.control.Label
        PluckPositionSlider        matlab.ui.control.Slider
        FileNameEditFieldLabel     matlab.ui.control.Label
        FileNameEditField          matlab.ui.control.EditField
        BridgeReflectonCoefficientSliderLabel  matlab.ui.control.Label
        BridgeReflectonCoefficientSlider  matlab.ui.control.Slider
        PickupPositionSliderLabel  matlab.ui.control.Label
        PickupPositionSlider       matlab.ui.control.Slider
        EffectsLabel               matlab.ui.control.Label
        ReverbCheckBox             matlab.ui.control.CheckBox
        Switch                     matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PluckButton
        function PluckButtonPushed(app, event)
            PluckedFunc(app.FundementalFrequencyHzEditField.Value, app.PluckPositionSlider.Value, app.PickupPositionSlider.Value, app.BridgeReflectonCoefficientSlider.Value, app.ReverbCheckBox.Value, app.Switch.Value, app.FileNameEditField.Value, app.SavewavfileCheckBox.Value, app.AnimateCheckBox.Value, app.TimeDomainCheckBox.Value, app.FrequnecyDomainCheckBox.Value, app.SpectrogramCheckBox.Value );
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AmbientPluckUIFigure = uifigure('Visible', 'off');
            app.AmbientPluckUIFigure.Name = 'Ambient Pluck';
            app.AnimateCheckBox = uicheckbox(app.AmbientPluckUIFigure);
            app.AnimateCheckBox.Text = 'Animate';
            app.TimeDomainCheckBox = uicheckbox(app.AmbientPluckUIFigure);
            app.TimeDomainCheckBox.Text = 'Time Domain';
            app.TimeDomainCheckBox.Value = true;
            app.FrequnecyDomainCheckBox = uicheckbox(app.AmbientPluckUIFigure);
            app.FrequnecyDomainCheckBox.Text = 'Frequnecy Domain';
            app.FrequnecyDomainCheckBox.Value = true;
            app.SpectrogramCheckBox = uicheckbox(app.AmbientPluckUIFigure);
            app.SpectrogramCheckBox.Text = 'Spectrogram';
            app.SpectrogramCheckBox.Value = true;
            app.PlotsLabel = uilabel(app.AmbientPluckUIFigure);
            app.PlotsLabel.Text = 'Plots';
            app.SavewavfileCheckBox = uicheckbox(app.AmbientPluckUIFigure);
            app.SavewavfileCheckBox.Text = 'Save .wav file';
            app.PluckButton = uibutton(app.AmbientPluckUIFigure, 'push');
            app.PluckButton.ButtonPushedFcn = createCallbackFcn(app, @PluckButtonPushed, true);
            app.PluckButton.Text = 'Pluck';
            app.FundementalFrequencyHzEditFieldLabel = uilabel(app.AmbientPluckUIFigure);
            app.FundementalFrequencyHzEditFieldLabel.Text = 'Fundemental Frequency (Hz)';
            app.FundementalFrequencyHzEditField = uieditfield(app.AmbientPluckUIFigure, 'numeric');
            app.FundementalFrequencyHzEditField.Value = 294;
            app.PluckPositionSliderLabel = uilabel(app.AmbientPluckUIFigure);
            app.PluckPositionSliderLabel.Text = 'Pluck Position';
            app.PluckPositionSlider = uislider(app.AmbientPluckUIFigure);
            app.PluckPositionSlider.Limits = [0 1];
            app.PluckPositionSlider.Value = 0.98;
            app.FileNameEditFieldLabel = uilabel(app.AmbientPluckUIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name:';
            app.FileNameEditField = uieditfield(app.AmbientPluckUIFigure, 'text');
            app.BridgeReflectonCoefficientSliderLabel = uilabel(app.AmbientPluckUIFigure);
            app.BridgeReflectonCoefficientSliderLabel.Text = 'Bridge Reflecton Coefficient';
            app.BridgeReflectonCoefficientSlider = uislider(app.AmbientPluckUIFigure);
            app.BridgeReflectonCoefficientSlider.Limits = [0.9 1];
            app.BridgeReflectonCoefficientSlider.Value = 0.995;
            app.PickupPositionSliderLabel = uilabel(app.AmbientPluckUIFigure);
            app.PickupPositionSliderLabel.Text = 'Pickup Position';
            app.PickupPositionSlider = uislider(app.AmbientPluckUIFigure);
            app.PickupPositionSlider.Limits = [0 1];
            app.PickupPositionSlider.Value = 0.5;
            app.EffectsLabel = uilabel(app.AmbientPluckUIFigure);
            app.EffectsLabel.Text = 'Effects';
            app.ReverbCheckBox = uicheckbox(app.AmbientPluckUIFigure);
            app.ReverbCheckBox.Text = 'Reverb';
            app.Switch = uiswitch(app.AmbientPluckUIFigure, 'slider');
            app.Switch.Items = {'Room', 'Hall'};
            app.Switch.Value = 'Hall';
            % Show the figure after all components are created
            app.AmbientPluckUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PluckedStringGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AmbientPluckUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AmbientPluckUIFigure)
        end
    end
end